classdef GalpSensor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        BancodeinformesTab     matlab.ui.container.Tab
        LabelDatos_5           matlab.ui.control.Label
        LabelDatos_4           matlab.ui.control.Label
        LabelDatos_3           matlab.ui.control.Label
        LabelDatos_2           matlab.ui.control.Label
        LabelEjm_4             matlab.ui.control.Label
        LabelDatos             matlab.ui.control.Label
        InformesinmediadosTab  matlab.ui.container.Tab
        LabelEjm_5             matlab.ui.control.Label
        InformeAhoraButton     matlab.ui.control.Button
        LabelDatos2            matlab.ui.control.Label
        AcercadeTab            matlab.ui.container.Tab
        LabelTitle_6           matlab.ui.control.Label
        LabelTitle_5           matlab.ui.control.Label
        LabelTitle_4           matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        LabelTitle_3           matlab.ui.control.Label
        LabelTitle_2           matlab.ui.control.Label
        LabelTitle             matlab.ui.control.Label
    end

    
    properties (Access = private)
        serial% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global aux;
            
            try
             app.serial=serialport("COM7",9600);
             aux=1;
            catch
            errordlg('Conecte el puerto!!') ;
          end
        if (aux==1)
            a=0;
        while(1)  
          palabra = read(app.serial,61,"string");
               Letra = char(palabra);
               app.LabelDatos.FontColor='Black';
             app.LabelDatos.Text=convertCharsToStrings(Letra);
               switch(a)
                 case 0
                     palabra2=palabra;
                     Letra2 = char(palabra2);
                     app.LabelDatos_2.FontColor='Black';
                     app.LabelDatos_2.Text=convertCharsToStrings(Letra2);
                 case 1
                     app.LabelDatos_3.FontColor='Black';
                     palabra3=palabra2;
                     Letra3 = char(palabra3);
                    app.LabelDatos_3.Text=convertCharsToStrings(Letra3);
                    palabra2=palabra;
                     Letra2 = char(palabra2);
                     app.LabelDatos_2.Text=convertCharsToStrings(Letra2);
                 case 2
                     app.LabelDatos_4.FontColor='Black';
                    palabra4=palabra3;
                     Letra4 = char(palabra4);
                    app.LabelDatos_4.Text=convertCharsToStrings(Letra4);
                    palabra3=palabra2;
                     Letra3 = char(palabra3);
                    app.LabelDatos_3.Text=convertCharsToStrings(Letra3);
                    palabra2=palabra;
                     Letra2 = char(palabra2);
                     app.LabelDatos_2.Text=convertCharsToStrings(Letra2);
                 case 3
                     app.LabelDatos_5.FontColor='Black';
                   palabra5=palabra4;
                     Letra5 = char(palabra5);
                    app.LabelDatos_5.Text=convertCharsToStrings(Letra5);
                     palabra4=palabra3;
                     Letra4 = char(palabra4);
                    app.LabelDatos_4.Text=convertCharsToStrings(Letra4);
                    palabra3=palabra2;
                     Letra3 = char(palabra3);
                    app.LabelDatos_3.Text=convertCharsToStrings(Letra3);
                    palabra2=palabra;
                     Letra2 = char(palabra2);
                     app.LabelDatos_2.Text=convertCharsToStrings(Letra2);
                  a=0;
             end
             a=a+1;
        end
            end
            
       
        end

        % Button pushed function: InformeAhoraButton
        function InformeAhoraButtonPushed(app, event)
            global aux;
            if(aux==1)
            numero="1";  
            write(app.serial,numero,"string");
               
              palabra2 = read(app.serial,61,"string");
              Letra2 = char(palabra2);
              app.LabelDatos2.FontColor='Black';
            app.LabelDatos2.Text=convertCharsToStrings(Letra2);
            end
           %CUADROS DE DIALOGOS
           %msgbox->Cuadro de dialogo normal
           %errordlg->Mensaje de error
           %warndlg->Mensaje de advertencia
           %helpdlg->Mesaje de ayuda
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'GALPSESOR!';
            app.LabelTitle = uilabel(app.UIFigure);
            app.LabelTitle.Text = 'GALPSENSOR';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BancodeinformesTab = uitab(app.TabGroup);
            app.BancodeinformesTab.Title = 'Banco de informes';
            app.LabelDatos = uilabel(app.BancodeinformesTab);
            app.LabelDatos.Text = '    (cm)                       (C°)                          (V)';
            app.LabelEjm_4 = uilabel(app.BancodeinformesTab);
            app.LabelEjm_4.Text = 'Distancia(cm)     Temperatura(C°)     Sonido(V)         ';
            app.LabelDatos_2 = uilabel(app.BancodeinformesTab);
            app.LabelDatos_2.Text = '    (cm)                       (C°)                          (V)';
            app.LabelDatos_3 = uilabel(app.BancodeinformesTab);
            app.LabelDatos_3.Text = '    (cm)                       (C°)                          (V)';
            app.LabelDatos_4 = uilabel(app.BancodeinformesTab);
            app.LabelDatos_4.Text = '    (cm)                       (C°)                          (V)';
            app.LabelDatos_5 = uilabel(app.BancodeinformesTab);
            app.LabelDatos_5.Text = '    (cm)                       (C°)                          (V)';
            app.InformesinmediadosTab = uitab(app.TabGroup);
            app.InformesinmediadosTab.Title = 'Informes inmediados';
            app.LabelDatos2 = uilabel(app.InformesinmediadosTab);
            app.LabelDatos2.Text = '    (cm)                       (C°)                          (V)';
            app.InformeAhoraButton = uibutton(app.InformesinmediadosTab, 'push');
            app.InformeAhoraButton.ButtonPushedFcn = createCallbackFcn(app, @InformeAhoraButtonPushed, true);
            app.InformeAhoraButton.Text = {'Informe'; 'Ahora'};
            app.LabelEjm_5 = uilabel(app.InformesinmediadosTab);
            app.LabelEjm_5.Text = 'Distancia(cm)     Temperatura(C°)     Sonido(V)         ';
            app.AcercadeTab = uitab(app.TabGroup);
            app.AcercadeTab.Title = 'Acerca de ';
            app.LabelTitle_2 = uilabel(app.AcercadeTab);
            app.LabelTitle_2.Text = 'Version: 1.0';
            app.LabelTitle_3 = uilabel(app.AcercadeTab);
            app.LabelTitle_3.Text = 'Todos los derechos reservados ';
            app.Label = uilabel(app.AcercadeTab);
            app.Label.Text = 'Para mas informacion visitar www.E&J_Company.com.co';
            app.LabelTitle_4 = uilabel(app.AcercadeTab);
            app.LabelTitle_4.Text = '© 2022 E&J Company';
            app.LabelTitle_5 = uilabel(app.AcercadeTab);
            app.LabelTitle_5.Text = 'GALPSENSOR';
            app.LabelTitle_6 = uilabel(app.AcercadeTab);
            app.LabelTitle_6.Text = 'Contacto: (+57)3205440370';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GalpSensor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end